classdef SSM_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SSM_UI_Figure           matlab.ui.Figure
        WARNINGLabel            matlab.ui.control.Label
        TCPIPClientfordatastreamingtoremoteserverLabel  matlab.ui.control.Label
        StopStreamingButton     matlab.ui.control.Button
        PortEditField           matlab.ui.control.NumericEditField
        PortEditFieldLabel      matlab.ui.control.Label
        IPadressEditField       matlab.ui.control.EditField
        IPadressEditFieldLabel  matlab.ui.control.Label
        StartStreamingButton    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartStreamingButton
        function StartStreamingButtonPushed(app, event)
            if SSM_InitTcpClient(app.IPadressEditField.Value, app.PortEditField.Value)
                set(app.StopStreamingButton,'Enable','on');
                set(app.StartStreamingButton,'Enable','off');
            end
        end

        % Button pushed function: StopStreamingButton
        function StopStreamingButtonPushed(app, event)
            if SSM_CloseTcpClient()
                set(app.StartStreamingButton,'Enable','on');
                set(app.StopStreamingButton,'Enable','off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SSM_UI_Figure = uifigure('Visible', 'off');
            app.SSM_UI_Figure.Name = 'ScanImage Streaming Module';
            app.StartStreamingButton = uibutton(app.SSM_UI_Figure, 'push');
            app.StartStreamingButton.ButtonPushedFcn = createCallbackFcn(app, @StartStreamingButtonPushed, true);
            app.StartStreamingButton.Text = 'Start Streaming';
            app.IPadressEditFieldLabel = uilabel(app.SSM_UI_Figure);
            app.IPadressEditFieldLabel.Text = 'IP adress';
            app.IPadressEditField = uieditfield(app.SSM_UI_Figure, 'text');
            app.IPadressEditField.Value = '172.25.187.213';
            app.PortEditFieldLabel = uilabel(app.SSM_UI_Figure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.SSM_UI_Figure, 'numeric');
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.Value = 55000;
            app.StopStreamingButton = uibutton(app.SSM_UI_Figure, 'push');
            app.StopStreamingButton.ButtonPushedFcn = createCallbackFcn(app, @StopStreamingButtonPushed, true);
            app.StopStreamingButton.Enable = 'off';
            app.StopStreamingButton.Text = 'Stop Streaming';
            app.TCPIPClientfordatastreamingtoremoteserverLabel = uilabel(app.SSM_UI_Figure);
            app.TCPIPClientfordatastreamingtoremoteserverLabel.Text = 'TCP/IP Client for data streaming to remote server';
            app.WARNINGLabel = uilabel(app.SSM_UI_Figure);
            app.WARNINGLabel.Text = {'WARNING: the TCP/IP connection must only be connected and'; 'disconnected via the matlab client! Make sure the server is running!'};
            % Show the figure after all components are created
            app.SSM_UI_Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SSM_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SSM_UI_Figure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SSM_UI_Figure)
        end
    end
end